classdef CalibrationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PIDListBoxLabel            matlab.ui.control.Label
        PIDListBox                 matlab.ui.control.ListBox
        TrialNameEditFieldLabel    matlab.ui.control.Label
        TrialNameEditField         matlab.ui.control.EditField
        HOCEditFieldLabel          matlab.ui.control.Label
        HOCEditField               matlab.ui.control.EditField
        ThumbEditFieldLabel        matlab.ui.control.Label
        ThumbEditField             matlab.ui.control.EditField
        IndexEditFieldLabel        matlab.ui.control.Label
        IndexEditField             matlab.ui.control.EditField
        MiddleEditFieldLabel       matlab.ui.control.Label
        MiddleEditField            matlab.ui.control.EditField
        RingEditFieldLabel         matlab.ui.control.Label
        RingEditField              matlab.ui.control.EditField
        PinkyEditFieldLabel        matlab.ui.control.Label
        PinkyEditField             matlab.ui.control.EditField
        MVCEditField_7Label        matlab.ui.control.Label
        MVCEditField               matlab.ui.control.EditField
        ThumbIndexEditFieldLabel   matlab.ui.control.Label
        ThumbIndexEditField        matlab.ui.control.EditField
        ThumbMiddleEditFieldLabel  matlab.ui.control.Label
        ThumbMiddleEditField       matlab.ui.control.EditField
        ThumbRingEditFieldLabel    matlab.ui.control.Label
        ThumbRingEditField         matlab.ui.control.EditField
        ThumbPinkyEditFieldLabel   matlab.ui.control.Label
        ThumbPinkyEditField        matlab.ui.control.EditField
        CalibrateButton            matlab.ui.control.Button
        CalibrationAppLabel        matlab.ui.control.Label
        UnprocessedFilesLabel      matlab.ui.control.Label
        LabelUnprocessed           matlab.ui.control.Label
        OrderDropDownLabel         matlab.ui.control.Label
        KinematicsOrder            matlab.ui.control.DropDown
        KineticsOrder              matlab.ui.control.DropDown
        TextArea                   matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        PID % Description
        curr_dir
        kmOrder = 0;
        kOrder = 0;
        kMax  = 4;  % Number of Movements in a Kinetics Trial
        kmMax = 10; % Number of Movements in a Kinematics Trial
    end
    
    methods (Access = private)
        
        function taskOrder = Initialize_kmOrderStruct(app)
            kmTask = ["HOC";"Thumb";"Index";"Middle";"Ring";"Pinky"];
            
            taskOrder = strings(1,6);
            count = 0;
            for i = 1:1:6
                if str2double(app.(sprintf('%sEditField',kmTask(i))).Value) ~= 0
                    taskOrder(str2double(app.(sprintf('%sEditField',kmTask(i))).Value)) = kmTask(i);
                    count = count + 1;
                end
            end
            taskOrder = taskOrder(1:count);
        end
        
        function taskOrder = Initialize_kOrderStruct(app)
            kTask = ["MVC";"ThumbIndex";"ThumbMiddle";"ThumbRing";"ThumbPinky"];
            taskOrder = strings(1,5);
            count = 0;
            for i = 1:1:5
                if str2double(app.(sprintf('%sEditField',kTask(i))).Value) ~= 0
                    taskOrder(str2double(app.(sprintf('%sEditField',kTask(i))).Value)) = kTask(i);
                    count = count + 1;
                end
            end
            taskOrder = taskOrder(1:count);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd(fileparts(which('CalibrationApp.mlapp')));
            addpath('Functions');
            app.curr_dir = pwd;
            cd ../../Patients
            patients = dir();
            patients = patients(3:end);
            patients = struct2cell(patients);
            patients = patients(1,:);
            patients{end+1} = 'New Patient';
            app.PIDListBox.Items = patients;
            cd(app.curr_dir);
            app.TrialNameEditField.Value = datestr(now,'yyyy_mm_dd');
        end

        % Value changed function: PIDListBox
        function PIDListBoxValueChanged(app, event)
            app.PID = app.PIDListBox.Value;
            cd ../../Patients
            cd(app.PID)
            cd 'Uncalibrated Data'

            filetype = strcat('*_raw_','*.mat');
            unprocessed_files = dir(filetype);
            app.LabelUnprocessed.Text = num2str(length(unprocessed_files));
            cd(app.curr_dir);
        end

        % Value changed function: KinematicsOrder
        function KinematicsOrderValueChanged(app, event)
            value = app.KinematicsOrder.Value;
            if strcmp(value,'First')
                app.kmOrder = 1;
            elseif strcmp(value,'Second')
                app.kmOrder = 2;
            elseif strcmp(value,'N/A')
                app.kmOrder = 0;
            end
        end

        % Value changed function: KineticsOrder
        function KineticsOrderValueChanged(app, event)
            value = app.KineticsOrder.Value;
            if strcmp(value,'First')
                app.kOrder = 1;
            elseif strcmp(value,'Second')
                app.kOrder = 2;
            elseif strcmp(value,'N/A')
                app.kOrder = 0;
            end
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            if app.kOrder == app.kmOrder
                app.TextArea.Value = "Kinematics and Kinetics cannot have the same order.";
                app.TextArea.FontSize = 20;
                app.TextArea.FontWeight = 'bold';
                app.TextArea.FontColor = 'r';
                pause(1);
                app.TextArea.FontWeight = 'normal';
                pause(1);
                app.TextArea.FontWeight = 'bold';
                return
            end
            cd(app.curr_dir)
            cd('..\..\Patients')
            cd(app.PID)
            cd cal
            load(strcat(app.PID,'_cal.mat'));
            cd ../'Uncalibrated Data'
            
            filetype = strcat('*_raw_','*.mat');
            unprocessed_files = dir(filetype);
            
            kmtaskOrder  = app.Initialize_kmOrderStruct();
            ktaskOrder = app.Initialize_kOrderStruct();
            
%             if (length(ktaskOrder)*app.kMax + length(kmtaskOrder)*app.kmMax) ~= length(unprocessed_files)
%                 app.TextArea.Value = "Kinematics and/or Kinetics listed order assumes more uncalibrated files than are present. Kinematics and kinetics require 20 and 6 per movement type, respectivly.";
%                 app.TextArea.FontSize = 15;
%                 app.TextArea.FontWeight = 'bold';
%                 app.TextArea.FontColor = 'r';
%                 pause(1);
%                 app.TextArea.FontWeight = 'normal';
%                 pause(1);
%                 app.TextArea.FontWeight = 'bold';
%                 return
%             end
            
            for i = 1:1:2
                filetype = strcat('*_raw_','*.mat');
                unprocessed_files = dir(filetype);
                if app.kOrder == i
                    
                    
                    trial_name = strcat(app.PID,'_',app.TrialNameEditField.Value,'_K');
                    mkdir(strcat(trial_name,'_raw_K'));
                    
                    for index = 1:1:length(ktaskOrder)
                        for mNum = 1:1:app.kMax
                            fileIndex = (index-1)*app.kMax + mNum;
                            load(unprocessed_files(fileIndex).name);
                            movefile(unprocessed_files(fileIndex).name,strcat(trial_name,'_raw_K'));
                            
                            here = pwd;
                            cd(app.curr_dir);
                            [angles,angles_deg,angles_f,angles_deg_f] = calibrateCGdata(rawData,offsets,gains,90);
                            cd(here);
                            
                            cd ../'Calibrated Data'
                            if ~exist(trial_name,'dir')
                                mkdir(trial_name);
                            end
                            cd(trial_name);
                            
                            save(strcat(app.PID,'_',ktaskOrder(index),int2str(mNum),'.mat'), 'angles', 'rawData', 'angles_deg','angles_f','angles_deg_f', 'data_time');
                            
                            cd ../../'Uncalibrated Data'
                            
                        end
                        
                    end
                    
                end
                
                if app.kmOrder == i
                    
                    trial_name = strcat(app.PID,'_',app.TrialNameEditField.Value,'_KM');
                    mkdir(strcat(trial_name,'_raw_KM'));
                    
                    for index = 1:1:length(kmtaskOrder)
                        for mNum = 1:1:app.kmMax
                            fileIndex = (index-1)*app.kmMax + mNum;
                            load(unprocessed_files(fileIndex).name);
                            movefile(unprocessed_files(fileIndex).name,strcat(trial_name,'_raw_KM'));
                            
                            here = pwd;
                            cd(app.curr_dir);
                            [angles,angles_deg,angles_f,angles_deg_f] = calibrateCGdata(rawData,offsets,gains,90);
                            cd(here);
                            
                            cd ../'Calibrated Data'
                            if ~exist(trial_name,'dir')
                                mkdir(trial_name);
                            end
                            cd(trial_name);
                            
                            save(strcat(app.PID,'_',kmtaskOrder(index),int2str(mNum),'.mat'), 'angles', 'rawData', 'angles_deg','angles_f','angles_deg_f', 'data_time');
                            
                            cd ../../'Uncalibrated Data'
                            
                        end
                        
                    end
                    
                end
                
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PIDListBoxLabel = uilabel(app.UIFigure);
            app.PIDListBoxLabel.Text = 'PID';
            app.PIDListBox = uilistbox(app.UIFigure);
            app.PIDListBox.ValueChangedFcn = createCallbackFcn(app, @PIDListBoxValueChanged, true);
            app.TrialNameEditFieldLabel = uilabel(app.UIFigure);
            app.TrialNameEditFieldLabel.Text = 'Trial Name';
            app.TrialNameEditField = uieditfield(app.UIFigure, 'text');
            app.HOCEditFieldLabel = uilabel(app.UIFigure);
            app.HOCEditFieldLabel.Text = 'HOC';
            app.HOCEditField = uieditfield(app.UIFigure, 'text');
            app.HOCEditField.Tag = 'kmTag';
            app.HOCEditField.Value = '0';
            app.ThumbEditFieldLabel = uilabel(app.UIFigure);
            app.ThumbEditFieldLabel.Text = 'Thumb';
            app.ThumbEditField = uieditfield(app.UIFigure, 'text');
            app.ThumbEditField.Tag = 'kmTag';
            app.ThumbEditField.Value = '0';
            app.IndexEditFieldLabel = uilabel(app.UIFigure);
            app.IndexEditFieldLabel.Text = 'Index';
            app.IndexEditField = uieditfield(app.UIFigure, 'text');
            app.IndexEditField.Tag = 'kmTag';
            app.IndexEditField.Value = '0';
            app.MiddleEditFieldLabel = uilabel(app.UIFigure);
            app.MiddleEditFieldLabel.Text = 'Middle';
            app.MiddleEditField = uieditfield(app.UIFigure, 'text');
            app.MiddleEditField.Tag = 'kmTag';
            app.MiddleEditField.Value = '0';
            app.RingEditFieldLabel = uilabel(app.UIFigure);
            app.RingEditFieldLabel.Text = 'Ring';
            app.RingEditField = uieditfield(app.UIFigure, 'text');
            app.RingEditField.Tag = 'kmTag';
            app.RingEditField.Value = '0';
            app.PinkyEditFieldLabel = uilabel(app.UIFigure);
            app.PinkyEditFieldLabel.Text = 'Pinky';
            app.PinkyEditField = uieditfield(app.UIFigure, 'text');
            app.PinkyEditField.Tag = 'kmTag';
            app.PinkyEditField.Value = '0';
            app.MVCEditField_7Label = uilabel(app.UIFigure);
            app.MVCEditField_7Label.Text = 'MVC';
            app.MVCEditField = uieditfield(app.UIFigure, 'text');
            app.MVCEditField.Value = '0';
            app.ThumbIndexEditFieldLabel = uilabel(app.UIFigure);
            app.ThumbIndexEditFieldLabel.Text = 'Thumb-Index';
            app.ThumbIndexEditField = uieditfield(app.UIFigure, 'text');
            app.ThumbIndexEditField.Value = '0';
            app.ThumbMiddleEditFieldLabel = uilabel(app.UIFigure);
            app.ThumbMiddleEditFieldLabel.Text = 'Thumb-Middle';
            app.ThumbMiddleEditField = uieditfield(app.UIFigure, 'text');
            app.ThumbMiddleEditField.Value = '0';
            app.ThumbRingEditFieldLabel = uilabel(app.UIFigure);
            app.ThumbRingEditFieldLabel.Text = 'Thumb-Ring';
            app.ThumbRingEditField = uieditfield(app.UIFigure, 'text');
            app.ThumbRingEditField.Value = '0';
            app.ThumbPinkyEditFieldLabel = uilabel(app.UIFigure);
            app.ThumbPinkyEditFieldLabel.Text = 'Thumb-Pinky';
            app.ThumbPinkyEditField = uieditfield(app.UIFigure, 'text');
            app.ThumbPinkyEditField.Value = '0';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.CalibrationAppLabel = uilabel(app.UIFigure);
            app.CalibrationAppLabel.Text = 'Calibration App';
            app.UnprocessedFilesLabel = uilabel(app.UIFigure);
            app.UnprocessedFilesLabel.Text = 'Unprocessed Files';
            app.LabelUnprocessed = uilabel(app.UIFigure);
            app.LabelUnprocessed.Text = '#';
            app.OrderDropDownLabel = uilabel(app.UIFigure);
            app.OrderDropDownLabel.Text = 'Order';
            app.KinematicsOrder = uidropdown(app.UIFigure);
            app.KinematicsOrder.Items = {'First', 'Second', 'N/A'};
            app.KinematicsOrder.ValueChangedFcn = createCallbackFcn(app, @KinematicsOrderValueChanged, true);
            app.KinematicsOrder.Value = 'N/A';
            app.KineticsOrder = uidropdown(app.UIFigure);
            app.KineticsOrder.Items = {'First', 'Second', 'N/A'};
            app.KineticsOrder.ValueChangedFcn = createCallbackFcn(app, @KineticsOrderValueChanged, true);
            app.KineticsOrder.Value = 'N/A';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'1. Create a trial name'; '2. Select PID that is being calibrated'; '3. What was the order of kinematics and kinetics if '; '    both trial types are unprocessed?'; '4. Within movement type, what was the order of the '; '    tasks performed?'; '5. Calibrate'; ''; 'NOTE: Kinematics is 20 movements per type'; '            Kinetics is 6 movements per type'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalibrationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end