classdef FS_Healthies < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TossTrialSwitchLabel     matlab.ui.control.Label
        TossTrialSwitch          matlab.ui.control.ToggleSwitch
        PromptTextArea           matlab.ui.control.TextArea
        StartButton              matlab.ui.control.Button
        PerformTaskLampLabel     matlab.ui.control.Label
        PerformTaskLamp          matlab.ui.control.Lamp
        PIDListBoxLabel          matlab.ui.control.Label
        PIDListBox               matlab.ui.control.ListBox
        CalibrateDataButton      matlab.ui.control.StateButton
        FlushCGButton            matlab.ui.control.Button
        TrialNameEditFieldLabel  matlab.ui.control.Label
        TrialNameEditField       matlab.ui.control.EditField
        ContinueButton           matlab.ui.control.StateButton
        HandDropDownLabel        matlab.ui.control.Label
        HandDropDown             matlab.ui.control.DropDown
        DebugModeCheckBox        matlab.ui.control.CheckBox
        MVCCheckBox              matlab.ui.control.CheckBox
        IndexCheckBox            matlab.ui.control.CheckBox
        MiddleCheckBox           matlab.ui.control.CheckBox
        RingCheckBox             matlab.ui.control.CheckBox
        PinkyCheckBox            matlab.ui.control.CheckBox
        SpinnerLabel             matlab.ui.control.Label
        Spinner                  matlab.ui.control.Spinner
        USBCheckBox              matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        % CyberGlove
        PID %patient ID
        minRest = 30; %seconds
        maxRest = 30; %seconds
        numMovements = 4 % per trial
        s
        tasks
        
        timestamps = strings(100);
        num_stamps = 0;
        last_mark = 0;
        
        sensors = ["TR" "IT" "OT" "TA" "II" "MI" "OI" "IM" "MM" "OM" "MA" "IR" "MR" "OR" "RA" "IP" "MP" "OP" "PA" "PR" "TW" "SW";...
            "Thumb Roll" "Thumb metacarpal" "Thumb proximal" "Thumb-index Abduction" "Index metacarpal" "Index proximal" "Index distal (no sensor)" "ABD" "Middle metacarpal" "Middle proximal" "Middle distal (no sensor)" "Index-middle abduction" "Ring metacarpal" "Ring proximal" "Ring distal (no sensor)" "Middle-ring abduction" "Pinky metacarpal" "Pinky proximal" "Pinky distal (no sensor)" "Ring-Pinky abduction" "Palm flex" "Wrist pitch"];
        
        debug
        
        %Prompts
        initial_prompt = ['You will be asked to perform one of five random grip tasks ', int2str(4) ,' times with a break in between each movement'];
        initial_prompt_time = 2;
        initial_prompt2 = 'A buzzer will sound and the light will turn green when a individual task starts. Maintain the grip until the light turns off and the buzzer sounds again. After, you can relax for the duration of the rest';
        initial_prompt3 = 'For the Whole-hand grip, squeeze the pipe as hard as you can. For this task you will have a 1 minute break';
        initial_prompt4 = 'For the (Finger)-Thumb Pinch grip, squeeze the pipe with only the specified finger and thumb without moving your other fingers. For this task, you will have a 30 second break.';
        
    end
    
    methods (Access = private)
        
        function app = WaitForContinueButton(app)
            app.ContinueButton.BackgroundColor = [0,1,0];
            uiwait(app.UIFigure);
        end
        
        function app = countdown2task(app)
            init_prompt = char(app.PromptTextArea.Value);
            app.PromptTextArea.Value = sprintf('%s \n \n %s',init_prompt, '3');
            pause(1);
            app.PromptTextArea.Value = sprintf('%s \n \n %s',init_prompt, '2');
            pause(1);
            app.PromptTextArea.Value = sprintf('%s \n \n %s',init_prompt, '1');
            pause(1);
            app.PromptTextArea.Value = init_prompt;
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd(fileparts(which('FS_Healthies.mlapp')));
            %Connects to CyberGlove
            try
                app.s = serialport("COM6", 115200);
                app.s.Parity = 'none';
                app.s.FlowControl = 'none';
                app.s.StopBits = 1;
                app.s.DataBits = 8;
                app.s.Timeout = 10;
            catch
                
            end
            
            app.PerformTaskLamp.Color = [1,0,0];
            
            addpath('Task_photos');
            addpath('Functions');
            
            curr_dir = pwd;
            cd ../../Patients
            patients = dir();
            patients = patients(3:end);
            patients = struct2cell(patients);
            patients = patients(1,:);
            patients{end+1} = 'New Patient';
            app.PIDListBox.Items = patients;
            cd(curr_dir);
            
            %Initialize Task Struct for loop
            %             task_names = ["MVC"; "Pinch"];
            %             task_prompts = ["Whole Hand"; "Index-Thumb Pinch"];
            %             task_photos = [""; ""];
            %             [app.tasks] = initializeTaskStruct(task_names,task_prompts,task_photos);
            
            %Moved to StartButtonFcn
            %             task_names = ["MVC"; "Index Pinch"; "Middle Pinch";"Ring Pinch";"Pinky Pinch"];
            %             task_prompts = ["Whole Hand"; "Index-Thumb Pinch"; "Middle-Thumb Pinch";"Ring-Thumb Pinch";"Pinky-Thumb Pinch"];
            %             task_photos = [""; ""; ""; ""; ""];
            %             [app.tasks] = initializeTaskStruct(task_names,task_prompts,task_photos);
            
            app.PromptTextArea.Value = "TekScan Trial: Ensure Patient Uncalibrated File is Clear";
            
        end

        % Value changed function: CalibrateDataButton
        function CalibrateDataButtonValueChanged(app, event)
            if length(fieldnames(app.tasks)) == 5
                app.PromptTextArea.Value = 'Need to run trial before calibrating';
                return
            end
            
            if isempty(app.TrialNameEditField.Value)
                app.PromptTextArea.Value = 'Enter a Trial Name before calibrating';
                return
            end
            
            properties.PID = app.PIDListBox.Value;
            final_order = strings(1,length(app.tasks) * app.numMovements);
            for i = 1:1:length(app.tasks)
                for j = 0:1:(app.numMovements-1)
                    final_order(((app.tasks(i).sequence-1) * app.numMovements) + j + 1) = strcat(app.tasks(i).names,int2str(j+1));
                end
                
                %final_order(app.tasks(i).sequence) = app.tasks(i).names;
            end
            
            
            cd(fileparts(which('FS_Healthies.mlapp')));
            curr_dir = pwd;
            cd('..\..\Patients')
            cd(properties.PID)
            cd cal
            load(strcat(properties.PID,'_cal.mat'));
            cd ../'Calibrated Data'
            save(strcat(app.TrialNameEditField.Value,'_order','.mat'),'final_order');
            cd ../'Uncalibrated Data'
            
            filetype = strcat(properties.PID,'_raw_','*.mat');
            unprocessed_files = dir(filetype);
            %fprintf("There are ");
            %fprintf(num2str(length(unprocessed_files)));
            %fprintf(" file(s) of unprocessed data\n");
            
            %allTasks = input("List task names deliminated by a comma and a space (i.e task1, task2, task3): ",'s');
            task_names = final_order;
            
            
            trial_name = strcat(properties.PID,'_',app.TrialNameEditField.Value);
            mkdir(strcat(trial_name,'_raw'));
            
            if length(task_names) ~= length(unprocessed_files)
                fprintf("Given number of task names does not equal the ");
                fprintf(num2str(length(unprocessed_files)));
                fprintf(" file(s) of unprocessed data\n");
                fprintf("CHECK THAT YOU LISTED TASKS WITH BOTH A COMMA THEN A SPACE (i.e task1, task2, task3)\n");
                cd(curr_dir)
                return
            end
            
            for index = 1:1:length(unprocessed_files)
                
                load(unprocessed_files(index).name);
                movefile(unprocessed_files(index).name,strcat(trial_name,'_raw'));
                
                here = pwd;
                cd(curr_dir);
                [angles,angles_deg,angles_f,angles_deg_f] = calibrateCGdata(rawData,offsets,gains,90);
                cd(here);
                
                cd ../'Calibrated Data'
                if ~exist(trial_name,'dir')
                    mkdir(trial_name);
                end
                cd(trial_name);
                
                save(strcat(properties.PID,'_',task_names{index},'.mat'), 'angles', 'rawData', 'angles_deg','angles_f','angles_deg_f', 'data_time');
                
                cd ../../'Uncalibrated Data'
                
            end
            
            
            cd(curr_dir);
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            properties.PID = app.PIDListBox.Value;
            
            task_names = ["MVC"; "Index Pinch"; "Middle Pinch";"Ring Pinch";"Pinky Pinch"];
            task_prompts = ["Whole Hand"; "Index-Thumb Pinch"; "Middle-Thumb Pinch";"Ring-Thumb Pinch";"Pinky-Thumb Pinch"];
            task_photos = [""; ""; ""; ""; ""];
            init_comp = [~app.MVCCheckBox.Value; ~app.IndexCheckBox.Value; ~app.MiddleCheckBox.Value; ~app.RingCheckBox.Value; ~app.PinkyCheckBox.Value];
            [app.tasks] = initializeTaskStruct(task_names,task_prompts,task_photos, init_comp);
            
            if properties.PID == "New Patient"
                fprintf("Run newPatient.m to create directory for new patient\n");
                close(app.UIFigure);
                return
            end
            
            %Initialize sound for start of streaming
            [y,Fs]=audioread('buzz.wav');
            player=audioplayer(y,Fs);


            firstPass = true;
            
            %             %Initialize Task Struct for loop
            %             task_names = ["thumb"; "index"; "middle"; "ring"; "pinky"; "hand open and close" ];
            %             task_prompts = ["Thumb"; "Index"; "Middle"; "Ring"; "Pinky"; "Open and Close Your Hand"];
            %             task_photos = ["Thumb.jpeg"; "Index.jpeg"; "Middle.jpeg"; "Ring.jpeg"; "Pinky.jpeg"; "HOC.jpeg"];
            %             [tasks] = initializeTaskStruct(task_names,task_prompts,task_photos);
            
            %Keeps track of task order
            order = 1;
            app.PromptTextArea.Value = app.initial_prompt;
            app = WaitForContinueButton(app);
            app.PromptTextArea.Value = app.initial_prompt2;
            app = WaitForContinueButton(app);
            app.PromptTextArea.Value = app.initial_prompt3;
            app = WaitForContinueButton(app);
            app.PromptTextArea.Value = app.initial_prompt4;
            app = WaitForContinueButton(app);
            
            [firstPass] = CG_acq_com('Start',app.s, properties.PID, player,90, firstPass);
            while sum([app.tasks(:).completed]) ~= length(app.tasks)
                [taskIndex] = randomNewTask(app.tasks);
                
                
                %Displays task prompt, marks task as completed, and records task order
                %[tasks] = performTask(taskIndex,tasks,order,false);
                app.tasks(taskIndex).sequence = order;
                
                app.PromptTextArea.Value = "The first squeeze is a test and should not be max effort";
                
                app = WaitForContinueButton(app);
                
                app.PromptTextArea.Value = app.tasks(taskIndex).prompts;
                app = WaitForContinueButton(app);
                
                app.tasks(taskIndex).completed = true;
                %                 [firstPass] = CG_acq_com('Toss',app.s, properties.PID, player,90, firstPass, app.timestamps, app.num_stamps, app.last_mark);
                
                i = 1;
                while i < app.numMovements + 1
                    app.Spinner.Value = i;
                    tic
                    [firstPass] = CG_acq_com('Toss',app.s, properties.PID, player,90, firstPass, app.timestamps, app.num_stamps, app.last_mark);
                    tossTime = toc;
                    tic
                    app = countdown2task(app);
                    %Plays Sound
                    app.tasks(taskIndex).timestamps(i) = datestr(now,'HH:MM:SS');
                    app.PerformTaskLamp.Color = [0,1,0];
                    play(player);
                    pause(3)
                    app.PerformTaskLamp.Color = [1,0,0];
                    play(player);
                    pause(1)
                    
                    
                    if strcmp(app.TossTrialSwitch.Value,'Yes')
                        %Toss data
                        
                        [firstPass] = CG_acq_com('Toss',app.s, properties.PID, player,90, firstPass, app.timestamps, app.num_stamps, app.last_mark);
                        
                        
                        %Resets task to incomplete
                        %tasks(taskIndex).completed = false;
                        i = 0;
                        app.TossTrialSwitch.Value = 'No';
                        app.PromptTextArea.Value = 'Reseting Task';
                        pause(2);
                        app.PromptTextArea.Value = 'Ready?';
                        app = WaitForContinueButton(app);
                        app.PromptTextArea.Value = app.tasks(taskIndex).prompts;
                    else
                        fromToss = toc;
                        
                        [firstPass] = CG_acq_com('Mark',app.s, properties.PID, player,90, firstPass, app.timestamps, app.num_stamps, app.last_mark);
                        saveTime = toc;
                        
                        if ~strcmp(app.tasks(taskIndex).names,"MVC")
                            r = randomRest_minus(app.minRest,app.maxRest,saveTime);
                        else
                            r = randomRest_minus(60,60,saveTime);
                        end
                        
                        actualRest = toc;
                        
                        if app.DebugModeCheckBox.Value
                            app.debug.add2Table(r, actualRest, fromToss, saveTime);
                        end
                        
                    end
                    i = i + 1;
                end
                %Saves Data from Task
                app.TossTrialSwitch.Value = 'No';
                
                
                %                 [firstPass] = CG_acq_com('Mark',app.s, properties.PID, player,90, firstPass, app.timestamps, app.num_stamps, app.last_mark);
                if sum([app.tasks(:).completed]) ~= length(app.tasks)
                    app.PromptTextArea.Value = 'Ready for next task?';
                    app = WaitForContinueButton(app);
                end
                %Increments task order
                order = order + 1;
                
            end
            
            CG_acq_com('End',app.s, properties.PID, player,90, firstPass, app.timestamps, app.num_stamps, app.last_mark);
            app.PromptTextArea.Value = 'Finished';
            
        end

        % Button pushed function: FlushCGButton
        function FlushCGButtonPushed(app, event)
            write(app.s,"!!!",'string');
            flush(app.s);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.s)
                write(app.s,"!!!",'string');
                flush(app.s);
            end
            delete(app)
        end

        % Value changed function: ContinueButton
        function ContinueButtonValueChanged(app, event)
            app.ContinueButton.BackgroundColor = [0.94,0.94,0.94];
            uiresume(app.UIFigure);
            
        end

        % Value changed function: HandDropDown
        function HandDropDownValueChanged(app, event)
            if strcmp(app.HandDropDown.Value,'Right')
                try
                    app.s = serialport("COM6", 115200);
                catch
                end
            else
                try
                    app.s = serialport("COM8", 115200);
                catch
                end
            end
            try
                app.s.Parity = 'none';
                app.s.FlowControl = 'none';
                app.s.StopBits = 1;
                app.s.DataBits = 8;
                app.s.Timeout = 10;
            catch
            end
            
        end

        % Value changed function: DebugModeCheckBox
        function DebugModeCheckBoxValueChanged(app, event)
            if app.DebugModeCheckBox.Value
                app.debug = DebugApp;
            end
            
        end

        % Value changed function: USBCheckBox
        function USBCheckBoxValueChanged(app, event)
            value = app.USBCheckBox.Value;
            if value 
                app.s = serialport("COM4", 115200);
            else
                if strcmp(app.HandDropDown.Value,'Right')
                    app.gauge = app.gauge.RightGauge();
                    app.s = serialport("COM6", 115200);
                    rmpath('Task_photos\Left hand');
                    addpath('Task_photos');
                    app.ImageRest.ImageSource = 'rest.jpg';
                    app.ImageRest_2.ImageSource = 'rest.jpg';
                else
                    app.gauge = app.gauge.LeftGauge();
                    app.s = serialport("COM8", 115200);
                    rmpath('Task_photos');
                    addpath('Task_photos\Left hand');
                    app.ImageRest.ImageSource = 'rest.jpg';
                    app.ImageRest_2.ImageSource = 'rest.jpg';
                end
            end
            app.s.Parity = 'none';
            app.s.FlowControl = 'none';
            app.s.StopBits = 1;
            app.s.DataBits = 8;
            app.s.Timeout = 10;
            
            if ~value
                writeCmd(app.s,'1ds','');


                writeCmd(app.s,'1du','');
            
                %No Wifi Streaming
                %write(app.s,"1dw",'string');
                writeCmd(app.s,'1ew','');
            
                writeCmd(app.s,'1w','');
            else
                writeCmd(app.s,'1ds','');
                writeCmd(app.s,'1eu','');
                writeCmd(app.s,'1dw','');
                writeCmd(app.s,'1u','');
            end
            
            pause(1)
            flush(app.s)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TossTrialSwitchLabel = uilabel(app.UIFigure);
            app.TossTrialSwitchLabel.Text = 'Toss Trial';
            app.TossTrialSwitch = uiswitch(app.UIFigure, 'toggle');
            app.TossTrialSwitch.Items = {'No', 'Yes'};
            app.TossTrialSwitch.Value = 'No';
            app.PromptTextArea = uitextarea(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PerformTaskLampLabel = uilabel(app.UIFigure);
            app.PerformTaskLampLabel.Text = 'Perform Task';
            app.PerformTaskLamp = uilamp(app.UIFigure);
            app.PIDListBoxLabel = uilabel(app.UIFigure);
            app.PIDListBoxLabel.Text = 'PID';
            app.PIDListBox = uilistbox(app.UIFigure);
            app.CalibrateDataButton = uibutton(app.UIFigure, 'state');
            app.CalibrateDataButton.ValueChangedFcn = createCallbackFcn(app, @CalibrateDataButtonValueChanged, true);
            app.CalibrateDataButton.Text = 'Calibrate Data';
            app.FlushCGButton = uibutton(app.UIFigure, 'push');
            app.FlushCGButton.ButtonPushedFcn = createCallbackFcn(app, @FlushCGButtonPushed, true);
            app.FlushCGButton.Text = 'Flush CG';
            app.TrialNameEditFieldLabel = uilabel(app.UIFigure);
            app.TrialNameEditFieldLabel.Text = 'Trial Name';
            app.TrialNameEditField = uieditfield(app.UIFigure, 'text');
            app.ContinueButton = uibutton(app.UIFigure, 'state');
            app.ContinueButton.ValueChangedFcn = createCallbackFcn(app, @ContinueButtonValueChanged, true);
            app.ContinueButton.Text = 'Continue';
            app.HandDropDownLabel = uilabel(app.UIFigure);
            app.HandDropDownLabel.Text = 'Hand';
            app.HandDropDown = uidropdown(app.UIFigure);
            app.HandDropDown.Items = {'Right', 'Left'};
            app.HandDropDown.ValueChangedFcn = createCallbackFcn(app, @HandDropDownValueChanged, true);
            app.HandDropDown.Value = 'Right';
            app.DebugModeCheckBox = uicheckbox(app.UIFigure);
            app.DebugModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @DebugModeCheckBoxValueChanged, true);
            app.DebugModeCheckBox.Text = 'Debug Mode';
            app.MVCCheckBox = uicheckbox(app.UIFigure);
            app.MVCCheckBox.Text = 'MVC';
            app.MVCCheckBox.Value = true;
            app.IndexCheckBox = uicheckbox(app.UIFigure);
            app.IndexCheckBox.Text = 'Index';
            app.IndexCheckBox.Value = true;
            app.MiddleCheckBox = uicheckbox(app.UIFigure);
            app.MiddleCheckBox.Text = 'Middle';
            app.MiddleCheckBox.Value = true;
            app.RingCheckBox = uicheckbox(app.UIFigure);
            app.RingCheckBox.Text = 'Ring';
            app.RingCheckBox.Value = true;
            app.PinkyCheckBox = uicheckbox(app.UIFigure);
            app.PinkyCheckBox.Text = 'Pinky';
            app.PinkyCheckBox.Value = true;
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = '#';
            app.Spinner = uispinner(app.UIFigure);
            app.USBCheckBox = uicheckbox(app.UIFigure);
            app.USBCheckBox.ValueChangedFcn = createCallbackFcn(app, @USBCheckBoxValueChanged, true);
            app.USBCheckBox.Text = 'USB';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FS_Healthies

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end