classdef GaugeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        PacingGaugeLabel  matlab.ui.control.Label
        PacingGauge       matlab.ui.control.NinetyDegreeGauge
        RestLabel         matlab.ui.control.Label
        FlexionLabel      matlab.ui.control.Label
        RestLabel_2       matlab.ui.control.Label
        FlexionLabel_2    matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property % Description
    end
    
    methods (Access = public)
        
        function app = RightGauge(app)
            app.PacingGauge.Orientation = 'northwest';
            app.PacingGauge.ScaleDirection = 'counterclockwise';
            app.FlexionLabel.Visible = true;
            app.RestLabel.Visible = true;
            app.FlexionLabel_2.Visible = false;
            app.RestLabel_2.Visible = false;
        end
        
        function app = LeftGauge(app)
            app.PacingGauge.Orientation = 'northeast';
            app.PacingGauge.ScaleDirection = 'clockwise';
            app.FlexionLabel.Visible = false;
            app.RestLabel.Visible = false;
            app.FlexionLabel_2.Visible = true;
            app.RestLabel_2.Visible = true;
            
        end
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PacingGaugeLabel = uilabel(app.UIFigure);
            app.PacingGaugeLabel.Text = 'Pacing';
            app.PacingGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.PacingGauge.MajorTicks = [0 50 100];
            app.PacingGauge.MajorTickLabels = {''};
            app.PacingGauge.ScaleDirection = 'counterclockwise';
            app.PacingGauge.MinorTicks = [];
            app.RestLabel = uilabel(app.UIFigure);
            app.RestLabel.Tag = 'RightLabel';
            app.RestLabel.Text = 'Rest';
            app.FlexionLabel = uilabel(app.UIFigure);
            app.FlexionLabel.Tag = 'RightLabel';
            app.FlexionLabel.Text = 'Flexion';
            app.RestLabel_2 = uilabel(app.UIFigure);
            app.RestLabel_2.Tag = 'RightLabel';
            app.RestLabel_2.Visible = 'off';
            app.RestLabel_2.Text = 'Rest';
            app.FlexionLabel_2 = uilabel(app.UIFigure);
            app.FlexionLabel_2.Tag = 'RightLabel';
            app.FlexionLabel_2.Visible = 'off';
            app.FlexionLabel_2.Text = 'Flexion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GaugeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end