classdef NihonKohden_Vis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        PIDDropDownLabel         matlab.ui.control.Label
        PIDDropDown              matlab.ui.control.DropDown
        TrialDropDownLabel       matlab.ui.control.Label
        TrialDropDown            matlab.ui.control.DropDown
        MovementDropDownLabel    matlab.ui.control.Label
        MovementDropDown         matlab.ui.control.DropDown
        EEGChannelDropDownLabel  matlab.ui.control.Label
        EEGChannelDropDown       matlab.ui.control.DropDown
        timeLB                   matlab.ui.control.NumericEditField
        timeUB                   matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        WindowLabel              matlab.ui.control.Label
        BandpassLB               matlab.ui.control.NumericEditField
        BandpassUB               matlab.ui.control.NumericEditField
        Label_2                  matlab.ui.control.Label
        BandpassHzLabel          matlab.ui.control.Label
        BandstopLB               matlab.ui.control.NumericEditField
        BandstopUB               matlab.ui.control.NumericEditField
        Label_3                  matlab.ui.control.Label
        BandstopHzLabel          matlab.ui.control.Label
        ApplyBSFilterButton      matlab.ui.control.Button
        ApplyBPFilterButton      matlab.ui.control.Button
        ApplyWindowButton        matlab.ui.control.Button
        LoadingLampLabel         matlab.ui.control.Label
        LoadingLamp              matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        PID % Patient ID
        trialname % Trial File Name
        movement % movement struct movement.min and movement.max
        EEGch % EEG Channel struct EEGch.name EEGch.index
        windowNum
        window % window struct window.min and window.max
        bp % bandpass struct bp.min and bp.max
        bs % bandstop struct bs.min and bs.max
        time_s
        v %filtered dampened DC
        f %bandpassed DC
        frameUB;
        frameLB = 1;
        
        EEG
        Channels
    end
    
    methods (Access = public)
        
        function sectionTTL(app, v)
            thresh = 0.4;
            above = false;
            markers = [];
            
            for i = 1:1:length(v)
                if ~above && v(i) > thresh
                    above = true;
                    markers(end+1) = i;


                elseif above && v(i) < thresh
                    above = false;
                end
                
            end
            
            app.window = zeros(length(markers)/2,2);
            
            app.window(:,1) = markers(1:2:end);
            app.window(:,2) = markers(2:2:end);
            
            app.MovementDropDown.Items = split(int2str(1:1:length(markers)/2),'  '); 


            %Using markers create window bounds
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd(fileparts(which('NihonKohden_Vis.mlapp')));
            addpath('Functions');
            
            curr_dir = pwd;
            cd ../../Patients
            patients = dir();
            patients = patients(3:end);
            patients = struct2cell(patients);
            patients = patients(1,:);
            app.PIDDropDown.Items = patients;
            cd(curr_dir)
            
            
        end

        % Value changed function: PIDDropDown
        function PIDDropDownValueChanged(app, event)
            value = app.PIDDropDown.Value;
            app.PID = value;
            
            curr_dir = pwd;
            cd ../../Patients
            cd(app.PID);
            cd('EEG');
            
            trials = dir();
            trials = trials(2:end);
            trials = struct2cell(trials);
            trials = trials(1,:);
            app.TrialDropDown.Items = trials;
            
            cd(curr_dir);
        end

        % Value changed function: TrialDropDown
        function TrialDropDownValueChanged(app, event)
            value = app.TrialDropDown.Value;
            app.trialname = value;
            
            curr_dir = pwd;
            cd ../../Patients
            cd(app.PID);
            cd('EEG');
            cd(app.trialname);
            
            app.LoadingLamp.Color = [1,0,0];
            pause(0.2)
            
            matfiles = dir('*.mat');
            matfiles = matfiles.name;
            load(matfiles, 'avg', 'label','time');
            app.EEG = avg;
            app.Channels = label(1,:);
            app.time_s = time;
            %app.Channels = struct2cell(app.Channels);
            %app.Channels = app.Channels(1,:);
            app.EEGChannelDropDown.Items = label(1:end);
            app.EEGch = str2double(app.EEGChannelDropDown.Value);
            
            app.timeLB.Value = 0;
            app.timeUB.Value = app.time_s(end);
            
            app.frameUB = length(app.time_s);
            
            pause(1);
            
            app.f = bandstop(app.EEG(36,:),[59,61],1000);
            app.v = app.f - mean(app.f(1:10000));
            plot(app.UIAxes,app.time_s,app.v);


            cd(curr_dir);
            
            sectionTTL(app, app.v);
            
            app.LoadingLamp.Color = [0,1,0];
        end

        % Value changed function: MovementDropDown
        function MovementDropDownValueChanged(app, event)
            value = app.MovementDropDown.Value;
            value = str2double(value);
            
            app.LoadingLamp.Color = [1,0,0];
            pause(0.2)
            
            plot(app.UIAxes,app.time_s(app.window(value,1):app.window(value,2)),app.v(app.window(value,1):app.window(value,2)));
            
            plot(app.UIAxes2,app.time_s(app.window(value,1):app.window(value,2)),app.EEG(app.EEGch,app.window(value,1):app.window(value,2)));
            
            app.timeLB.Value = app.time_s(app.window(value,1));
            app.timeUB.Value = app.time_s(app.window(value,2));
            app.frameLB = app.window(value,1);
            app.frameUB = app.window(value,2);
            
            app.LoadingLamp.Color = [0,1,0];
        end

        % Button pushed function: ApplyBSFilterButton
        function ApplyBSFilterButtonPushed(app, event)
            filtEEG = bandstop(app.EEG(app.EEGch,app.frameLB:app.frameUB),[double(app.BandstopLB),double(app.BandstopUB)],1000);
            plot(app.UIAxes2,app.time_s(app.frameLB:app.frameUB),filtEEG);
        end

        % Value changed function: EEGChannelDropDown
        function EEGChannelDropDownValueChanged(app, event)
            value = app.EEGChannelDropDown.Value;
            value = str2double(value);
            app.EEGch = value;
            app.LoadingLamp.Color = [1,0,0];
            pause(0.2)
            plot(app.UIAxes2,app.time_s(app.frameLB:app.frameUB),app.EEG(app.EEGch,app.frameLB:app.frameUB));
            app.LoadingLamp.Color = [0,1,0];
            
        end

        % Button pushed function: ApplyWindowButton
        function ApplyWindowButtonPushed(app, event)
            app.LoadingLamp.Color = [1,0,0];
            pause(0.2)


            app.frameLB = floor(app.timeLB.Value * 1000);
            app.frameUB = ceil(app.timeUB.Value * 1000);
            
            plot(app.UIAxes,app.time_s(app.frameLB:app.frameUB),app.v(app.frameLB:app.frameUB));
            
            plot(app.UIAxes2,app.time_s(app.frameLB:app.frameUB),app.EEG(app.EEGch,app.frameLB:app.frameUB));
            
            app.LoadingLamp.Color = [0,1,0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'TTL')
            xlabel(app.UIAxes, 'Seconds')
            ylabel(app.UIAxes, 'mV?')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'EEG Channel')
            xlabel(app.UIAxes2, 'Seconds')
            ylabel(app.UIAxes2, 'mV?')
            app.PIDDropDownLabel = uilabel(app.UIFigure);
            app.PIDDropDownLabel.Text = 'PID';
            app.PIDDropDown = uidropdown(app.UIFigure);
            app.PIDDropDown.ValueChangedFcn = createCallbackFcn(app, @PIDDropDownValueChanged, true);
            app.TrialDropDownLabel = uilabel(app.UIFigure);
            app.TrialDropDownLabel.Text = 'Trial';
            app.TrialDropDown = uidropdown(app.UIFigure);
            app.TrialDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialDropDownValueChanged, true);
            app.MovementDropDownLabel = uilabel(app.UIFigure);
            app.MovementDropDownLabel.Text = 'Movement';
            app.MovementDropDown = uidropdown(app.UIFigure);
            app.MovementDropDown.ValueChangedFcn = createCallbackFcn(app, @MovementDropDownValueChanged, true);
            app.EEGChannelDropDownLabel = uilabel(app.UIFigure);
            app.EEGChannelDropDownLabel.Text = 'EEG Channel';
            app.EEGChannelDropDown = uidropdown(app.UIFigure);
            app.EEGChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @EEGChannelDropDownValueChanged, true);
            app.timeLB = uieditfield(app.UIFigure, 'numeric');
            app.timeUB = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-';
            app.WindowLabel = uilabel(app.UIFigure);
            app.WindowLabel.Text = 'Window';
            app.BandpassLB = uieditfield(app.UIFigure, 'numeric');
            app.BandpassUB = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '-';
            app.BandpassHzLabel = uilabel(app.UIFigure);
            app.BandpassHzLabel.Text = 'Bandpass (Hz)';
            app.BandstopLB = uieditfield(app.UIFigure, 'numeric');
            app.BandstopUB = uieditfield(app.UIFigure, 'numeric');
            app.BandstopUB.RoundFractionalValues = 'on';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '-';
            app.BandstopHzLabel = uilabel(app.UIFigure);
            app.BandstopHzLabel.Text = 'Bandstop (Hz)';
            app.ApplyBSFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyBSFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyBSFilterButtonPushed, true);
            app.ApplyBSFilterButton.Text = 'Apply Filter';
            app.ApplyBPFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyBPFilterButton.Text = 'Apply Filter';
            app.ApplyWindowButton = uibutton(app.UIFigure, 'push');
            app.ApplyWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyWindowButtonPushed, true);
            app.ApplyWindowButton.Text = 'Apply Filter';
            app.LoadingLampLabel = uilabel(app.UIFigure);
            app.LoadingLampLabel.Text = 'Loading';
            app.LoadingLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NihonKohden_Vis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end