classdef TestGauge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StartButton               matlab.ui.control.Button
        DropDownLabel             matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        UITable                   matlab.ui.control.Table
        WantedTimeEditFieldLabel  matlab.ui.control.Label
        WantedTimeEditField       matlab.ui.control.NumericEditField
        SaveDataButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        gauge % Description
    end
    
    methods (Access = private)
        
        function PacingMovement(app,input,time)
            tic
            for i = 0:1:(time*1000)
                pause(1/1000);
                input.Value = floor((i/(time*1000))*100);
            end
            timed = toc;
            add2Table(app, time, timed);
            
            for i = (time*1000):-1:0
                pause(1/1000);
                input.Value = floor((i/(time*1000))*100);
            end
            input.Value = 0;
            %             results = input;
        end
        
        function add2Table(app, data1, data2)
            app.UITable.Data = [app.UITable.Data; {data1 data2}];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.gauge = GaugeApp;
            addpath('Functions');
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            PacingMovement(app,app.gauge.PacingGauge,app.WantedTimeEditField.Value);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            if strcmp(app.DropDown.Value,'Left')
                %                 app.gauge.PacingGauge.Orientation = 'northeast';
                %                 app.gauge.PacingGauge.ScaleDirection = 'clockwise';
                %                 app.gauge.L
                app.gauge = app.gauge.LeftGauge();
            else
                %                 app.gauge.PacingGauge.Orientation = 'northwest';
                %                 app.gauge.PacingGauge.ScaleDirection = 'counterclockwise';
                app.gauge = app.gauge.RightGauge();
            end
            
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            data = app.UITable.Data;
            save('pacing_time_test','data');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Left', 'Right', '', ''};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Right';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Wanted Time'; 'Actual'};
            app.UITable.RowName = {};
            app.WantedTimeEditFieldLabel = uilabel(app.UIFigure);
            app.WantedTimeEditFieldLabel.Text = 'Wanted Time';
            app.WantedTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.WantedTimeEditField.Value = 0.7;
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestGauge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end