classdef appHealthies < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TossTrialSwitchLabel     matlab.ui.control.Label
        TossTrialSwitch          matlab.ui.control.ToggleSwitch
        PromptTextArea           matlab.ui.control.TextArea
        StartButton              matlab.ui.control.Button
        PerformTaskLampLabel     matlab.ui.control.Label
        PerformTaskLamp          matlab.ui.control.Lamp
        PIDListBoxLabel          matlab.ui.control.Label
        PIDListBox               matlab.ui.control.ListBox
        ImageMove                matlab.ui.control.Image
        RestingHandLabel         matlab.ui.control.Label
        PromptedMovementLabel    matlab.ui.control.Label
        ImageRest                matlab.ui.control.Image
        CalibrateDataButton      matlab.ui.control.StateButton
        FlushCGButton            matlab.ui.control.Button
        RestingHandLabel_2       matlab.ui.control.Label
        ImageRest_2              matlab.ui.control.Image
        TrialNameEditFieldLabel  matlab.ui.control.Label
        TrialNameEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        % CyberGlove
        PID %patient ID
        minRest = 1; %seconds
        maxRest = 3; %seconds
        numMovements = 1 % per trial
        s
        tasks
        
        timestamps = strings(100);
        num_stamps = 0;
        last_mark = 0;

        sensors = ["TR" "IT" "OT" "TA" "II" "MI" "OI" "IM" "MM" "OM" "MA" "IR" "MR" "OR" "RA" "IP" "MP" "OP" "PA" "PR" "TW" "SW";...
                   "Thumb Roll" "Thumb metacarpal" "Thumb proximal" "Thumb-index Abduction" "Index metacarpal" "Index proximal" "Index distal (no sensor)" "ABD" "Middle metacarpal" "Middle proximal" "Middle distal (no sensor)" "Index-middle abduction" "Ring metacarpal" "Ring proximal" "Ring distal (no sensor)" "Middle-ring abduction" "Pinky metacarpal" "Pinky proximal" "Pinky distal (no sensor)" "Ring-Pinky abduction" "Palm flex" "Wrist pitch"];
        
        %Prompts
        initial_prompt = 'You will be asked to perform a random movement 10 times with a break of a random length in between each movement';
        initial_prompt_time = 2;


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Connects to CyberGlove
            app.s = serialport("COM6", 115200);
            app.s.Parity = 'none';
            app.s.FlowControl = 'none';
            app.s.StopBits = 1;
            app.s.DataBits = 8;
            app.s.Timeout = 10;
            
            app.PerformTaskLamp.Color = [1,0,0];
            
            addpath('C:\Users\le40619\Desktop\OR Code\CyberGlove\OR Directory\Code\Intraoperative-CyberGlove\Task_photos');
            app.ImageRest.ImageSource = 'rest.jpeg';
            app.ImageRest_2.ImageSource = 'rest.jpeg';
            
            curr_dir = pwd;
            cd ../../Patients
            patients = dir();
            patients = patients(3:end);
            patients = struct2cell(patients);
            patients = patients(1,:);
            patients{end+1} = 'New Patient';
            app.PIDListBox.Items = patients;
            cd(curr_dir);
            
            %Initialize Task Struct for loop
            task_names = ["thumb"; "index"; "middle"; "ring"; "pinky"; "HOC" ];
            task_prompts = ["Thumb"; "Index"; "Middle"; "Ring"; "Pinky"; "Close and Open Your Hand"];
            task_photos = ["Thumb.jpeg"; "Index.jpeg"; "Middle.jpeg"; "Ring.jpeg"; "Pinky.jpeg"; "HOC.jpeg"];
            [app.tasks] = initializeTaskStruct(task_names,task_prompts,task_photos);
            
            app.PromptTextArea.Value = "CyberGlove Trial: Ensure Patient Uncalibrated File is Clear";
            
        end

        % Value changed function: CalibrateDataButton
        function CalibrateDataButtonValueChanged(app, event)
            if length(fieldnames(app.tasks)) == 5
                app.PromptTextArea.Value = 'Need to run trial before calibrating';
                return
            end
           
            if isempty(app.TrialNameEditField.Value)
                app.PromptTextArea.Value = 'Enter a Trial Name before calibrating';
                return
            end 

            properties.PID = app.PIDListBox.Value;
            final_order = strings(1,length(app.tasks));
            for i = 1:1:length(app.tasks)
                final_order(app.tasks(i).sequence) = app.tasks(i).names; 
            end
            curr_dir = pwd;
            cd('C:\Users\le40619\Desktop\OR Code\CyberGlove\OR Directory\Patients')
            cd(properties.PID)
            cd cal
            load(strcat(properties.PID,'_cal.mat'));
            cd ../'Uncalibrated Data'
            
            filetype = strcat(properties.PID,'_raw_','*.mat');
            unprocessed_files = dir(filetype);
            %fprintf("There are ");
            %fprintf(num2str(length(unprocessed_files)));
            %fprintf(" file(s) of unprocessed data\n");
            
            %allTasks = input("List task names deliminated by a comma and a space (i.e task1, task2, task3): ",'s');
            task_names = final_order;
            
            
            trial_name = strcat(properties.PID,app.TrialNameEditField.Value);
            mkdir(strcat(trial_name,'_raw'));
            
            if length(task_names) ~= length(unprocessed_files)
               fprintf("Given number of task names does not equal the ");
               fprintf(num2str(length(unprocessed_files)));
               fprintf(" file(s) of unprocessed data\n");
               fprintf("CHECK THAT YOU LISTED TASKS WITH BOTH A COMMA THEN A SPACE (i.e task1, task2, task3)\n");
               cd(curr_dir)
               return 
            end
            
             for index = 1:1:length(unprocessed_files)
                 
                 load(unprocessed_files(index).name); 
                 movefile(unprocessed_files(index).name,strcat(trial_name,'_raw'));
                 
                 here = pwd;
                 cd(curr_dir);
                 [angles,angles_deg,angles_f,angles_deg_f] = calibrateCGdata(rawData,offsets,gains,90);
                 cd(here);
                 
                 cd ../'Calibrated Data'
                 if ~exist(trial_name,'dir')
                    mkdir(trial_name);
                 end
                 cd(trial_name);
              
                 save(strcat(properties.PID,'_',task_names{index},'.mat'), 'angles', 'rawData', 'angles_deg','angles_f','angles_deg_f', 'data_time');
                 
                 cd ../../'Uncalibrated Data'
                  
             end
             
            cd(curr_dir);
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            properties.PID = app.PIDListBox.Value;
            if properties.PID == "New Patient"
                fprintf("Run newPatient.m to create directory for new patient\n");
                close(app.UIFigure);
                return
            end
            
            %Initialize sound for start of streaming
            [y,Fs]=audioread('buzz.wav');
            player=audioplayer(y,Fs);


            firstPass = true;
            
%             %Initialize Task Struct for loop
%             task_names = ["thumb"; "index"; "middle"; "ring"; "pinky"; "hand open and close" ];
%             task_prompts = ["Thumb"; "Index"; "Middle"; "Ring"; "Pinky"; "Open and Close Your Hand"];
%             task_photos = ["Thumb.jpeg"; "Index.jpeg"; "Middle.jpeg"; "Ring.jpeg"; "Pinky.jpeg"; "HOC.jpeg"];
%             [tasks] = initializeTaskStruct(task_names,task_prompts,task_photos);
            
            %Keeps track of task order
            order = 1;
            app.PromptTextArea.Value = app.initial_prompt;
            pause(app.initial_prompt_time);
            
            [firstPass] = CG_acq_com('Start',app.s, properties.PID, player,90, firstPass);
            while sum([app.tasks(:).completed]) ~= length(app.tasks)
                [taskIndex] = randomNewTask(app.tasks);
                [firstPass] = CG_acq_com('Toss',app.s, properties.PID, player,90, firstPass, app.timestamps, app.num_stamps, app.last_mark);
                
                %Displays task prompt, marks task as completed, and records task order
                %[tasks] = performTask(taskIndex,tasks,order,false);
                app.tasks(taskIndex).sequence = order;
                app.PromptTextArea.Value = app.tasks(taskIndex).prompts;
                app.ImageMove.ImageSource = app.tasks(taskIndex).photos;
                app.tasks(taskIndex).completed = true;
                
   
                i = 1;
                while i < app.numMovements + 1
                    pause(3)
                    %Plays Sound
                    app.tasks(taskIndex).timestamps(i) = datestr(now,'HH:MM:SS');
                    app.PerformTaskLamp.Color = [0,1,0];
                    play(player);
                    pause(1);
                    app.PerformTaskLamp.Color = [1,0,0];
                    
                    
                    if strcmp(app.TossTrialSwitch.Value,'Yes')
                        %Toss data
                        [firstPass] = CG_acq_com('Toss',app.s, properties.PID, player,90, firstPass, app.timestamps, app.num_stamps, app.last_mark);
                        %Resets task to incomplete
                        %tasks(taskIndex).completed = false;
                        i = 0;
                        app.TossTrialSwitch.Value = 'No';
                        app.PromptTextArea.Value = 'Reseting Task';
                        pause(2);
                        app.PromptTextArea.Value = app.tasks(taskIndex).prompts;
                    else
                        randomRest(app.minRest,app.maxRest);
                    end
                    i = i + 1;
                end
                %Saves Data from Task
                app.TossTrialSwitch.Value = 'No';
                app.PromptTextArea.Value = '...Saving';
                pause(1);
                [firstPass] = CG_acq_com('Mark',app.s, properties.PID, player,90, firstPass, app.timestamps, app.num_stamps, app.last_mark);
                %Increments task order
                order = order + 1;
                
            end
            
            CG_acq_com('End',app.s, properties.PID, player,90, firstPass, app.timestamps, app.num_stamps, app.last_mark);
            app.PromptTextArea.Value = 'Finished';
            
        end

        % Button pushed function: FlushCGButton
        function FlushCGButtonPushed(app, event)
            write(app.s,"!!!",'string');
            flush(app.s);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            write(app.s,"!!!",'string');
            flush(app.s);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TossTrialSwitchLabel = uilabel(app.UIFigure);
            app.TossTrialSwitchLabel.Text = 'Toss Trial';
            app.TossTrialSwitch = uiswitch(app.UIFigure, 'toggle');
            app.TossTrialSwitch.Items = {'No', 'Yes'};
            app.TossTrialSwitch.Value = 'No';
            app.PromptTextArea = uitextarea(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PerformTaskLampLabel = uilabel(app.UIFigure);
            app.PerformTaskLampLabel.Text = 'Perform Task';
            app.PerformTaskLamp = uilamp(app.UIFigure);
            app.PIDListBoxLabel = uilabel(app.UIFigure);
            app.PIDListBoxLabel.Text = 'PID';
            app.PIDListBox = uilistbox(app.UIFigure);
            app.ImageMove = uiimage(app.UIFigure);
            app.RestingHandLabel = uilabel(app.UIFigure);
            app.RestingHandLabel.Text = 'Resting Hand';
            app.PromptedMovementLabel = uilabel(app.UIFigure);
            app.PromptedMovementLabel.Text = 'Prompted Movement';
            app.ImageRest = uiimage(app.UIFigure);
            app.CalibrateDataButton = uibutton(app.UIFigure, 'state');
            app.CalibrateDataButton.ValueChangedFcn = createCallbackFcn(app, @CalibrateDataButtonValueChanged, true);
            app.CalibrateDataButton.Text = 'Calibrate Data';
            app.FlushCGButton = uibutton(app.UIFigure, 'push');
            app.FlushCGButton.ButtonPushedFcn = createCallbackFcn(app, @FlushCGButtonPushed, true);
            app.FlushCGButton.Text = 'Flush CG';
            app.RestingHandLabel_2 = uilabel(app.UIFigure);
            app.RestingHandLabel_2.Text = 'Resting Hand';
            app.ImageRest_2 = uiimage(app.UIFigure);
            app.TrialNameEditFieldLabel = uilabel(app.UIFigure);
            app.TrialNameEditFieldLabel.Text = 'Trial Name';
            app.TrialNameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appHealthies

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end