classdef appHealthies_VP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TossTrialSwitchLabel     matlab.ui.control.Label
        TossTrialSwitch          matlab.ui.control.ToggleSwitch
        PromptTextArea           matlab.ui.control.TextArea
        StartButton              matlab.ui.control.Button
        PerformTaskLampLabel     matlab.ui.control.Label
        PerformTaskLamp          matlab.ui.control.Lamp
        PIDListBoxLabel          matlab.ui.control.Label
        PIDListBox               matlab.ui.control.ListBox
        CalibrateDataButton      matlab.ui.control.StateButton
        TrialNameEditFieldLabel  matlab.ui.control.Label
        TrialNameEditField       matlab.ui.control.EditField
        ContinueButton           matlab.ui.control.Button
        ImageMove                matlab.ui.control.Image
        StartPositionLabel       matlab.ui.control.Label
        PromptedMovementLabel    matlab.ui.control.Label
        ImageRest                matlab.ui.control.Image
        EndPositionLabel         matlab.ui.control.Label
        ImageRest_2              matlab.ui.control.Image
        HandDropDownLabel        matlab.ui.control.Label
        HandDropDown             matlab.ui.control.DropDown
        Label                    matlab.ui.control.Label
        Spinner                  matlab.ui.control.Spinner
        DebugModeCheckBox        matlab.ui.control.CheckBox
        DUMPButton               matlab.ui.control.Button
        HandCheckBox             matlab.ui.control.CheckBox
        ThumbCheckBox            matlab.ui.control.CheckBox
        IndexCheckBox            matlab.ui.control.CheckBox
        MiddleCheckBox           matlab.ui.control.CheckBox
        RingCheckBox             matlab.ui.control.CheckBox
        PinkyCheckBox            matlab.ui.control.CheckBox
        ConnectionDropDownLabel  matlab.ui.control.Label
        ConnectionDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        % CyberGlove
        PID %patient ID
        minRest = 5; %seconds
        maxRest =15; %seconds
        numMovements = 10 % per trial
        s
        tasks
        
        timestamps = strings(100);
        num_stamps = 0;
        last_mark = 0;
        
        sensors = ["TR" "IT" "OT" "TA" "II" "MI" "OI" "IM" "MM" "OM" "MA" "IR" "MR" "OR" "RA" "IP" "MP" "OP" "PA" "PR" "TW" "SW";...
            "Thumb Roll" "Thumb metacarpal" "Thumb proximal" "Thumb-index Abduction" "Index metacarpal" "Index proximal" "Index distal (no sensor)" "ABD" "Middle metacarpal" "Middle proximal" "Middle distal (no sensor)" "Index-middle abduction" "Ring metacarpal" "Ring proximal" "Ring distal (no sensor)" "Middle-ring abduction" "Pinky metacarpal" "Pinky proximal" "Pinky distal (no sensor)" "Ring-Pinky abduction" "Palm flex" "Wrist pitch"];
        
        %Prompts
        initial_prompt  = ['You will be asked to perform one of six different movements ',int2str(10),' times. There will be a break of ',int2str(5),' to ',int2str(15),' seconds between each individual movement.'];
        initial_prompt2 = 'The light will turn green and a buzzer will sound when you should start a movement. The gauge will be used to pace your movement speed. After performing the movement return to the end position for the duration of the rest.';
        initial_prompt3 = 'Look at the gauge to see an example of what the pacing will look like.'
        initial_prompt_time = 2;


        debug % Description
        gauge
    end
    
    methods (Access = private)
        
        function app = waitForContinue(app)
            app.ContinueButton.BackgroundColor = [0, 1, 0];
            uiwait(app.UIFigure);
        end
        
        function PacingMovement(~,input,time)
            %0.7
            for i = 0:1:(time*1000)
                pause(1/1000);
                input.Value = floor((i/(time*1000))*100);
            end
            
            
            for i = (time*1000):-1:0
                pause(1/1000);
                input.Value = floor((i/(time*1000))*100);
            end
            input.Value = 0;
            %             results = input;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd(fileparts(which('appHealthies_VP.mlapp')));
            addpath('Functions');
            %Connects to CyberGlove
            try
            app.s = serialport("COM6", 115200);
            app.s.Parity = 'none';
            app.s.FlowControl = 'none';
            app.s.StopBits = 1;
            app.s.DataBits = 8;
            app.s.Timeout = 10;
            catch
                app.PromptTextArea.Value ="SWITCH DROPDOWN TO CORRECT HAND";
                pause(1);
            end
            
            
            app.PerformTaskLamp.Color = [1,0,0];
            
            addpath('Task_photos');
            app.ImageRest.ImageSource = 'rest.jpg';
            app.ImageRest_2.ImageSource = 'rest.jpg';
            
            curr_dir = pwd;
            cd ../../Patients
            patients = dir();
            patients = patients(3:end);
            patients = struct2cell(patients);
            patients = patients(1,:);
            patients{end+1} = 'New Patient';
            app.PIDListBox.Items = patients;
            cd(curr_dir);
            
            %MOVED TO START
%             %Initialize Task Struct for loop
%             task_names = ["thumb"; "index"; "middle"; "ring"; "pinky"; "HOC" ];
%             task_prompts = ["Thumb"; "Index"; "Middle"; "Ring"; "Pinky"; "Close and Open Your Hand"];
%             task_photos = ["Thumb.jpg"; "Index.jpg"; "Middle.jpg"; "Ring.jpg"; "Pinky.jpg"; "HOC.jpg"];
%             init_comp = [~app.ThumbCheckBox.Value; ~app.IndexCheckBox.Value; ~app.MiddleCheckBox.Value; ~app.RingCheckBox.Value; ~app.PinkyCheckBox.Value; ~app.HandCheckBox.Value ];
%             [app.tasks] = initializeTaskStruct(task_names,task_prompts,task_photos,init_comp);
            
            app.PromptTextArea.Value = "CyberGlove Trial: Ensure Patient Uncalibrated File is Clear";
            
            app.gauge = GaugeApp;
            
        end

        % Value changed function: CalibrateDataButton
        function CalibrateDataButtonValueChanged(app, event)
            if length(fieldnames(app.tasks)) == 5
                app.PromptTextArea.Value = 'Need to run trial before calibrating';
                return
            end
            
            if isempty(app.TrialNameEditField.Value)
                app.PromptTextArea.Value = 'Enter a Trial Name before calibrating';
                return
            end
            
            properties.PID = app.PIDListBox.Value;
            final_order = strings(1,length(app.tasks) * app.numMovements);
            for i = 1:1:length(app.tasks)
                for j = 0:1:(app.numMovements-1)
                    final_order(((app.tasks(i).sequence-1) * app.numMovements) + j + 1) = strcat(app.tasks(i).names,int2str(j+1));
                end
                
                %final_order(app.tasks(i).sequence) = app.tasks(i).names;
            end
            
            
            cd(fileparts(which('appHealthies_VP.mlapp')));
            curr_dir = pwd;
            cd('..\..\Patients')
            cd(properties.PID)
            cd cal
            load(strcat(properties.PID,'_cal.mat'));
            cd ../'Calibrated Data'
            save(strcat(app.TrialNameEditField.Value,'_order','.mat'),'final_order');
            cd ../'Uncalibrated Data'
            
            filetype = strcat(properties.PID,'_raw_','*.mat');
            unprocessed_files = dir(filetype);
            %fprintf("There are ");
            %fprintf(num2str(length(unprocessed_files)));
            %fprintf(" file(s) of unprocessed data\n");
            
            %allTasks = input("List task names deliminated by a comma and a space (i.e task1, task2, task3): ",'s');
            task_names = final_order;
            
            
            trial_name = strcat(properties.PID,'_',app.TrialNameEditField.Value);
            mkdir(strcat(trial_name,'_raw'));
            
            if length(task_names) ~= length(unprocessed_files)
                fprintf("Given number of task names does not equal the ");
                fprintf(num2str(length(unprocessed_files)));
                fprintf(" file(s) of unprocessed data\n");
                fprintf("CHECK THAT YOU LISTED TASKS WITH BOTH A COMMA THEN A SPACE (i.e task1, task2, task3)\n");
                cd(curr_dir)
                return
            end
            
            for index = 1:1:length(unprocessed_files)
                
                load(unprocessed_files(index).name);
                movefile(unprocessed_files(index).name,strcat(trial_name,'_raw'));
                
                here = pwd;
                cd(curr_dir);
                [angles,angles_deg,angles_f,angles_deg_f] = calibrateCGdata(rawData,offsets,gains,90);
                cd(here);
                
                cd ../'Calibrated Data'
                if ~exist(trial_name,'dir')
                    mkdir(trial_name);
                end
                cd(trial_name);
                
                save(strcat(properties.PID,'_',task_names{index},'.mat'), 'angles', 'rawData', 'angles_deg','angles_f','angles_deg_f', 'data_time');
                
                cd ../../'Uncalibrated Data'
                
            end
            
            
            cd(curr_dir);
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            properties.PID = app.PIDListBox.Value;
            
            %Initialize Task Struct for loop
            task_names = ["thumb"; "index"; "middle"; "ring"; "pinky"; "HOC" ];
            task_prompts = ["Thumb"; "Index"; "Middle"; "Ring"; "Pinky"; "Close and Open Your Hand"];
            task_photos = ["Thumb.jpg"; "Index.jpg"; "Middle.jpg"; "Ring.jpg"; "Pinky.jpg"; "HOC.jpg"];
            init_comp = [~app.ThumbCheckBox.Value; ~app.IndexCheckBox.Value; ~app.MiddleCheckBox.Value; ~app.RingCheckBox.Value; ~app.PinkyCheckBox.Value; ~app.HandCheckBox.Value ];
            [app.tasks] = initializeTaskStruct(task_names,task_prompts,task_photos,init_comp);
            
            if properties.PID == "New Patient"
                fprintf("Run newPatient.m to create directory for new patient\n");
                close(app.UIFigure);
                return
            end
            
            %Initialize sound for start of streaming
            [y,Fs]=audioread('buzz.wav');
            player=audioplayer(y,Fs);
            PacingMovement(app,app.gauge.PacingGauge,0.5);
            
            
            firstPass = true;
            
            
            %Keeps track of task order
            order = 1;
            app.PromptTextArea.Value = app.initial_prompt;
            app = waitForContinue(app);
            app.PromptTextArea.Value = app.initial_prompt2;
            app = waitForContinue(app);
            app.PromptTextArea.Value = app.initial_prompt3;
            app = waitForContinue(app);
            PacingMovement(app,app.gauge.PacingGauge,0.5);
            app = waitForContinue(app);
            
            while sum([app.tasks(:).completed]) ~= length(app.tasks)
                if sum([app.tasks(:).completed]) ~= 0
                    app.PromptTextArea.Value = 'Ready for Next Task?';
                    app = waitForContinue(app);
                end
                [taskIndex] = randomNewTask(app.tasks);


                %new%
                flush(app.s);
                
                
                %Displays task prompt, marks task as completed, and records task order
                %[tasks] = performTask(taskIndex,tasks,order,false);
                app.tasks(taskIndex).sequence = order;
                app.PromptTextArea.Value = app.tasks(taskIndex).prompts;
                app.ImageMove.ImageSource = app.tasks(taskIndex).photos;
                app.tasks(taskIndex).completed = true;
                
                %new%
                [firstPass] = CG_acq_com('Start',app.s, properties.PID, player,90, firstPass);
                
                pause(5) % Must be longer than 4 or first movement gets cut off due to SD card streaming
                
                i = 1;
                while i < app.numMovements + 1
                    app.Spinner.Value = i;
                    %pause(1);
                    tic
                    [firstPass] = CG_acq_com('Toss',app.s, properties.PID, player,90, firstPass, app.timestamps, app.num_stamps, app.last_mark);
                    tossTime = toc;
                    
                    %Plays Sound
                    app.tasks(taskIndex).timestamps(i) = datestr(now,'HH:MM:SS');
                    
                    pause(2);
                    
                    app.PerformTaskLamp.Color = [0,1,0];
                    play(player);
                    PacingMovement(app,app.gauge.PacingGauge,0.5);
                    app.PerformTaskLamp.Color = [1,0,0];
                    pause(3);
                    
                    %pause(6); FOR participant MCW001's second trial 
                    
                    
                    if strcmp(app.TossTrialSwitch.Value,'Yes')
                        %Toss data
                        [firstPass] = CG_acq_com('Toss',app.s, properties.PID, player,90, firstPass, app.timestamps, app.num_stamps, app.last_mark);
                        %Resets task to incomplete
                        %tasks(taskIndex).completed = false;
                        i = 0;
                        app.TossTrialSwitch.Value = 'No';
                        app.PromptTextArea.Value = 'Reseting Task';
                        pause(2);
                        
                        app.PromptTextArea.Value = app.tasks(taskIndex).prompts;
                    else
                        tic
                        try
                            [firstPass] = CG_acq_com('Mark',app.s, properties.PID, player,90, firstPass, app.timestamps, app.num_stamps, app.last_mark);
                        catch
                            app.PromptTextArea.Value = "Last Motion File contained No Data: RESET";
                            return
                        end
                        
                        saveTime = toc;
                        if i ~= 20
                            r = randomRest_minus(app.minRest,app.maxRest,(saveTime));
                            actualRest = toc;
                            
                             if app.DebugModeCheckBox.Value
                                app.debug.add2Table(r, actualRest, tossTime, saveTime);
                            end
                            
                            %fprintf("random5-15 // actual rest // \n")
                            %fprintf(strcat(string(r),' // ',string(actualRest),' // ','','\n'));
                        end
                    end
                    i = i + 1;
                end
                %Unchecks completed a task after all movements are completed
                app = checkOffTask(taskIndex, app);
                
                %Saves Data from Task
                app.TossTrialSwitch.Value = 'No';
                
                %%%
                %SD Card file is missing last 4 movements
                %pause(5); DID NOT WORK
                %%%
                CG_acq_com('End',app.s, properties.PID, player,90, firstPass, app.timestamps, app.num_stamps, app.last_mark);
                order = order + 1;


            end
            
            %old%CG_acq_com('End',app.s, properties.PID, player,90, firstPass, app.timestamps, app.num_stamps, app.last_mark);
            app.PromptTextArea.Value = 'Finished';
            
        end

        % Callback function
        function FlushCGButtonPushed(app, event)
            write(app.s,"!!!",'string');
            flush(app.s);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.s)
                try
                    write(app.s,"!!!",'string');
                catch
                    warning("Glove disconnected");
                end
            end
            if isempty(app.tasks) || length(fieldnames(app.tasks)) <= 5
                delete(app.gauge)
                delete(app)
                return
            end
            done = questdlg('Have you calibrated the data?', ...
                'Data?' ,'Yes','Cancel','Cancel');
            if strcmp(done,'Yes')
                delete(app.gauge)
                delete(app)
                
            else
                return
            end
            
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            uiresume(app.UIFigure);
            app.ContinueButton.BackgroundColor = [0.94 0.94 0.94];
        end

        % Value changed function: HandDropDown
        function HandDropDownValueChanged(app, event)
            if strcmp(app.HandDropDown.Value,'Right')
                app.gauge = app.gauge.RightGauge();
                try
                    app.s = serialport("COM6", 115200);
                catch
                    warning('No wifi connection detected for Right glove')
                    app.s = serialport("COM4", 115200);
                    app.ConnectionDropDown.Value = "USB";
                    app.s.Parity = 'none';
                    app.s.FlowControl = 'none';
                    app.s.StopBits = 1;
                    app.s.DataBits = 8;
                    app.s.Timeout = 10;
                    writeCmd(app.s,'1ds','');
                    writeCmd(app.s,'1eu','');
                    writeCmd(app.s,'1dw','');
                    writeCmd(app.s,'1u','');
                    pause(1)
                    flush(app.s);
                    
                end  
                rmpath('Task_photos\Left hand');
                addpath('Task_photos');
                app.ImageRest.ImageSource = 'rest.jpg';
                app.ImageRest_2.ImageSource = 'rest.jpg';
            else
                app.gauge = app.gauge.LeftGauge();
                try
                    app.s = serialport("COM8", 115200);
                catch
                    warning('No wifi connection detected for Right glove')
                    app.s = serialport("COM4", 115200);
                    app.ConnectionDropDown.Value = "USB";
                    app.s.Parity = 'none';
                    app.s.FlowControl = 'none';
                    app.s.StopBits = 1;
                    app.s.DataBits = 8;
                    app.s.Timeout = 10;
                    writeCmd(app.s,'1ds','');
                    writeCmd(app.s,'1eu','');
                    writeCmd(app.s,'1dw','');
                    writeCmd(app.s,'1u','');
                    pause(1)
                    flush(app.s);
                end  
                rmpath('Task_photos');
                addpath('Task_photos\Left hand');
                app.ImageRest.ImageSource = 'rest.jpg';
                app.ImageRest_2.ImageSource = 'rest.jpg';
            end
            app.s.Parity = 'none';
            app.s.FlowControl = 'none';
            app.s.StopBits = 1;
            app.s.DataBits = 8;
            app.s.Timeout = 10;
            
        end

        % Value changed function: DebugModeCheckBox
        function DebugModeCheckBoxValueChanged(app, event)
            if app.DebugModeCheckBox.Value
                app.debug = DebugApp;
            end
            
        end

        % Button pushed function: DUMPButton
        function DUMPButtonPushed(app, event)
            write(app.s,'!!!','string');
            fprintf(datestr(now,'HH:MM:SS'));
            fprintf('\n');
            read(app.s,62,'char')
            close(app.UIFigure);
        end

        % Value changed function: ConnectionDropDown
        function ConnectionDropDownValueChanged(app, event)
            
            if strcmp(app.ConnectionDropDown.Value,'WiFi')
                if strcmp(app.HandDropDown.Value,'Right')
                    app.gauge = app.gauge.RightGauge();
                    app.s = serialport("COM6", 115200);
                    rmpath('Task_photos\Left hand');
                    addpath('Task_photos');
                    app.ImageRest.ImageSource = 'rest.jpg';
                    app.ImageRest_2.ImageSource = 'rest.jpg';
                else
                    app.gauge = app.gauge.LeftGauge();
                    app.s = serialport("COM8", 115200);
                    rmpath('Task_photos');
                    addpath('Task_photos\Left hand');
                    app.ImageRest.ImageSource = 'rest.jpg';
                    app.ImageRest_2.ImageSource = 'rest.jpg';
                end
            else
                app.s = serialport("COM4", 115200);
            end
            app.s.Parity = 'none';
            app.s.FlowControl = 'none';
            app.s.StopBits = 1;
            app.s.DataBits = 8;
            app.s.Timeout = 10;


            if ~strcmp(app.ConnectionDropDown.Value,'USB')
                %WiFi Streaming
                writeCmd(app.s,'1ds','');
                writeCmd(app.s,'1du','');
                writeCmd(app.s,'1ew','');
                writeCmd(app.s,'1w','');
            else
                %USB Streaming
                writeCmd(app.s,'1ds','');
                writeCmd(app.s,'1eu','');
                writeCmd(app.s,'1dw','');
                writeCmd(app.s,'1u','');
            end
            
            pause(1)


            flush(app.s)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TossTrialSwitchLabel = uilabel(app.UIFigure);
            app.TossTrialSwitchLabel.Text = 'Toss Trial';
            app.TossTrialSwitch = uiswitch(app.UIFigure, 'toggle');
            app.TossTrialSwitch.Items = {'No', 'Yes'};
            app.TossTrialSwitch.Value = 'No';
            app.PromptTextArea = uitextarea(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PerformTaskLampLabel = uilabel(app.UIFigure);
            app.PerformTaskLampLabel.Text = 'Perform Task';
            app.PerformTaskLamp = uilamp(app.UIFigure);
            app.PIDListBoxLabel = uilabel(app.UIFigure);
            app.PIDListBoxLabel.Text = 'PID';
            app.PIDListBox = uilistbox(app.UIFigure);
            app.CalibrateDataButton = uibutton(app.UIFigure, 'state');
            app.CalibrateDataButton.ValueChangedFcn = createCallbackFcn(app, @CalibrateDataButtonValueChanged, true);
            app.CalibrateDataButton.Text = 'Calibrate Data';
            app.TrialNameEditFieldLabel = uilabel(app.UIFigure);
            app.TrialNameEditFieldLabel.Text = 'Trial Name';
            app.TrialNameEditField = uieditfield(app.UIFigure, 'text');
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.ImageMove = uiimage(app.UIFigure);
            app.StartPositionLabel = uilabel(app.UIFigure);
            app.StartPositionLabel.Text = 'Start Position';
            app.PromptedMovementLabel = uilabel(app.UIFigure);
            app.PromptedMovementLabel.Text = 'Prompted Movement';
            app.ImageRest = uiimage(app.UIFigure);
            app.EndPositionLabel = uilabel(app.UIFigure);
            app.EndPositionLabel.Text = 'End Position';
            app.ImageRest_2 = uiimage(app.UIFigure);
            app.HandDropDownLabel = uilabel(app.UIFigure);
            app.HandDropDownLabel.Text = 'Hand';
            app.HandDropDown = uidropdown(app.UIFigure);
            app.HandDropDown.Items = {'Right', 'Left', ''};
            app.HandDropDown.ValueChangedFcn = createCallbackFcn(app, @HandDropDownValueChanged, true);
            app.HandDropDown.Value = 'Right';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '#';
            app.Spinner = uispinner(app.UIFigure);
            app.DebugModeCheckBox = uicheckbox(app.UIFigure);
            app.DebugModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @DebugModeCheckBoxValueChanged, true);
            app.DebugModeCheckBox.Text = 'Debug Mode';
            app.DUMPButton = uibutton(app.UIFigure, 'push');
            app.DUMPButton.ButtonPushedFcn = createCallbackFcn(app, @DUMPButtonPushed, true);
            app.DUMPButton.Text = 'DUMP';
            app.HandCheckBox = uicheckbox(app.UIFigure);
            app.HandCheckBox.Text = 'Hand';
            app.HandCheckBox.Value = true;
            app.ThumbCheckBox = uicheckbox(app.UIFigure);
            app.ThumbCheckBox.Text = 'Thumb';
            app.ThumbCheckBox.Value = true;
            app.IndexCheckBox = uicheckbox(app.UIFigure);
            app.IndexCheckBox.Text = 'Index';
            app.IndexCheckBox.Value = true;
            app.MiddleCheckBox = uicheckbox(app.UIFigure);
            app.MiddleCheckBox.Text = 'Middle';
            app.MiddleCheckBox.Value = true;
            app.RingCheckBox = uicheckbox(app.UIFigure);
            app.RingCheckBox.Text = 'Ring';
            app.RingCheckBox.Value = true;
            app.PinkyCheckBox = uicheckbox(app.UIFigure);
            app.PinkyCheckBox.Text = 'Pinky';
            app.PinkyCheckBox.Value = true;
            app.ConnectionDropDownLabel = uilabel(app.UIFigure);
            app.ConnectionDropDownLabel.Text = 'Connection';
            app.ConnectionDropDown = uidropdown(app.UIFigure);
            app.ConnectionDropDown.Items = {'WiFi', 'USB'};
            app.ConnectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ConnectionDropDownValueChanged, true);
            app.ConnectionDropDown.Value = 'WiFi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appHealthies_VP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end